classdef FinalWork1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        ColorToGrayMenu     matlab.ui.container.Menu
        b                   matlab.ui.container.Menu
        GrayToBinMenu       matlab.ui.container.Menu
        GrayTransMenu       matlab.ui.container.Menu
        e                   matlab.ui.container.Menu
        EdgeExtractionMenu  matlab.ui.container.Menu
        AddNoiseMenu        matlab.ui.container.Menu
        h                   matlab.ui.container.Menu
        TextArea            matlab.ui.control.TextArea
        Label_2             matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Label               matlab.ui.control.Label
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Picture % Description
        GrayPicture % Description
        PictureOfGauss % Description
        PictureOfPepper % Description
        FinishWork % Description
        Pattern % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file,path] = uigetfile(filterspec);
            imagefile = [path file];
            im = imread(imagefile);
            app.Picture=im;
            imagesc(app.UIAxes,im);

            a=app.Picture;
            R=im2double(a(:,:,1));
            G=im2double(a(:,:,2));
            B=im2double(a(:,:,3));
            A3=a;
            %提取图像的RGB三色分量

            i=round((R+B+G)/3*255);
            A3(:,:,1)=i;
            A3(:,:,2)=i;
            A3(:,:,3)=i;
            %使分个分量值等于平均值
            app.GrayPicture=A3;

            AN=app.GrayPicture;
            [height,width,channel]=size(AN);
            if channel==3
                AN=AN(:,:,1);
                % 将三维矩阵变为二维矩阵
            end
            J=imnoise(AN,'gauss',0.02);
            app.PictureOfGauss=J;
            % 添加高斯噪声
            J1= imnoise(AN,'salt & pepper');
            app.PictureOfPepper=J1;
            % 添加椒盐噪声
            app.Pattern='ColorToGray';
            app.TextArea.Value=app.Pattern;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.Pattern;
            switch value


                case 'ColorToGray'
                    imshow(app.GrayPicture,"Parent",app.UIAxes_2);
                    app.FinishWork=app.GrayPicture;


                case 'GrayToBin'
                    GTB=app.GrayPicture;
                    val=app.Slider.Value; %val设计成滑动条

                    [a,b]=size(GTB);
                    for x=1:a
                        for y=1:b
                            if GTB(x,y)>val
                                %将滑动条的值设置为阈值
                                GTB(x,y)=255;
                            else
                                GTB(x,y)=0;
                            end
                        end
                    end
                    imshow(GTB,"Parent",app.UIAxes_2);
                    app.FinishWork=GTB;


                case 'GrayTrans'

                    GT=app.GrayPicture;
                    GT1=im2double(GT);

                    var=app.Slider.Value;

                    if (var<64)  %线性变换
                        a1=0;
                        b1=255;
                        a0=50;
                        b0=250;
                        GT2=a1+(b1-a1)/(b0-a0)*(GT-a0);
                        imshow(GT2,"Parent",app.UIAxes_2);
                        app.FinishWork=GT2;
                    end

                    if (64<=var)&&(var<128)  %对数变换
                        GT3=log(GT1+1);
                        imshow(GT3,"Parent",app.UIAxes_2);
                        app.FinishWork=GT3;
                    end

                    if (128<=var)&&(var<192) %幂次变换3
                        GT4=GT1.^3;
                        imshow(GT4,"Parent",app.UIAxes_2);
                        app.FinishWork=GT4;
                    end

                    if (192<=var)&&(var<=256) %幂次变换0.3
                        GT5=GT1.^0.3;
                        imshow(GT5,"Parent",app.UIAxes_2);
                        app.FinishWork=GT5;
                    end

                case 'AddNoise'
                    var=app.Slider.Value;
                    if(var<128)
                        imshow(app.PictureOfGauss,"Parent",app.UIAxes_2);
                        app.FinishWork=app.PictureOfGauss;
                    else
                        imshow(app.PictureOfPepper,"Parent",app.UIAxes_2);
                        app.FinishWork=app.PictureOfPepper;
                    end

                case 'Filter'

                    var=app.Slider.Value;

                    if(var<43)
                        FL=app.PictureOfPepper;
                        a=fspecial('average',3);
                        %生成3*3模板的均值滤波
                        FL1=uint8(filter2(a,FL));
                        %进行均值滤波运算
                        imshow(FL1,"Parent",app.UIAxes_2);
                        app.FinishWork=FL1;
                    end

                    if(var>=43)&&(var<86)
                        FL=app.PictureOfPepper;
                        FL2=uint8(medfilt2(FL,[3 3]));
                        %对图像进行3*3模板的中值滤波计算
                        imshow(FL2,"Parent",app.UIAxes_2);
                        app.FinishWork=FL2;
                    end


                    if(var>=86)&&(var<128)
                        FL=app.PictureOfGauss;
                        c=fspecial('gaussian',3);
                        %生成3*3模板的高斯滤波
                        FL3=uint8(filter2(c,FL));
                        %进行高斯滤波运算
                        imshow(FL3,"Parent",app.UIAxes_2);
                        app.FinishWork=FL3;
                    end

                    if(var>=128)&&(var<171)
                        FL=app.PictureOfPepper;
                        a=fspecial('average',5);
                        %生成5*5模板的均值滤波
                        FL4=uint8(filter2(a,FL));
                        %进行均值滤波运算
                        imshow(FL4,"Parent",app.UIAxes_2);
                        app.FinishWork=FL4;
                    end

                    if(var>=171)&&(var<213)
                        FL=app.PictureOfPepper;
                        FL5=uint8(medfilt2(FL,[5 5]));
                        %对图像进行5*5模板的中值滤波计算
                        imshow(FL5,"Parent",app.UIAxes_2);
                        app.FinishWork=FL5;
                    end

                    if(var>=213)&&(var<=256)
                        FL=app.PictureOfGauss;
                        c=fspecial('gaussian',5);
                        %生成5*5模板的高斯滤波
                        FL6=uint8(filter2(c,FL));
                        %进行高斯滤波运算
                        imshow(FL6,"Parent",app.UIAxes_2);
                        app.FinishWork=FL6;
                    end

                case 'EdgeExtraction'
                    EE=app.GrayPicture; %原图像为灰度图

                    threshold= graythresh(EE);  %使用最大类间方差法找到图片的一个合适的阈值(threshold=0.4745)
                    EE_1=im2bw(EE,threshold); %用这个阈值把灰度图像转换为二值图像(像素值>0.4745*255的变为255白，其他的变为0黑)

                    EE_2=bwperim(EE_1); %用bwperim函数查找二值图像的边缘
                    imshow(EE_2,"Parent",app.UIAxes_2);
                    
                    app.FinishWork=EE_2;

                case 'HistogramEqu'

                    HE=app.GrayPicture;
                    [x,y]=size(HE);
                    h=zeros(1,256);
                    for i=1:256
                        h(i)=length(find(HE==(i-1)))/(x*y);
                        %依次找到a数组中0到255灰度值的向量，在用length计算出0到255灰度值向量所对应的个数，
                        %在除以像素总个数，最后将得到的数值p依次存入矩阵h
                    end
                    figure;
                    subplot(2,2,1);imshow(HE);title("原图像");
                    subplot(2,2,2);bar(0:255,h);
                    %绘制原图像的直方图
                    s1=zeros(1,256);
                    s2=zeros(1,256);
                    t=0;
                    for i=1:256
                        t=t+h(i);
                        s1(i)=t;
                        %对数值p进行累加计算出新的灰度级
                    end
                    s2=round(s1*255);
                    %将s1中的灰度级修正为合理的灰度级
                    s3=zeros(1,256);
                    for i=1:256
                        s3(i)=sum(h(s2==(i-1)));
                        %在h矩阵中找到与修正后的灰度级对应的灰度级所对应的比重值，如原始灰度级对应同样修正后的灰度级则将他们相加。
                    end
                    subplot(2,2,4);bar(0:255,s3);
                    %绘制均衡化后图像的直方图
                    c=HE;
                    for i=1:256
                        c(HE==(i-1))=s2(i);
                        %将均衡化后的灰度值值一一对应给图像c，从而获得直方图均衡化后的图像
                    end
                    subplot(2,2,3);imshow(c);title("直方图均衡化处理后图像")
                    app.FinishWork=c;

                case 'PictureStrong'
                    % 读取图像
                    PS = app.Picture;
                    f=mat2gray(PS);

                    % 定义伽马值
                    var=app.Slider.Value;
                    gamma = var*2/255;
                    a=1;

                    % 幂律变换
                    PS_gamma = a*(f.^gamma);

                    % 显示增强后的图像
                    imshow(PS_gamma,"Parent",app.UIAxes_2);
                    app.FinishWork=PS_gamma;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [file,path]= uiputfile('.jpg','保存图片');
            if isequal(file,0) || isequal(path,0)
                return
            else
                imwrite(app.FinishWork,[path,file]);
            end
        end

        % Menu selected function: ColorToGrayMenu
        function ColorToGrayMenuSelected(app, event)
            app.Pattern='ColorToGray';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: b
        function bSelected(app, event)
            app.Pattern='PictureStrong';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: GrayToBinMenu
        function GrayToBinMenuSelected(app, event)
            app.Pattern='GrayToBin';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: GrayTransMenu
        function GrayTransMenuSelected(app, event)
            app.Pattern='GrayTrans';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: e
        function eMenuSelected(app, event)
            app.Pattern='Filter';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: EdgeExtractionMenu
        function EdgeExtractionMenuSelected(app, event)
            app.Pattern='EdgeExtraction';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: AddNoiseMenu
        function AddNoiseMenuSelected(app, event)
            app.Pattern='AddNoise';
            app.TextArea.Value=app.Pattern;
        end

        % Menu selected function: h
        function hMenuSelected(app, event)
            app.Pattern='HistogramEqu';
            app.TextArea.Value=app.Pattern;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '模式选择';
            app.ColorToGrayMenu = uimenu(app.Menu);
            app.ColorToGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorToGrayMenuSelected, true);
            app.ColorToGrayMenu.Accelerator = 'a';
            app.ColorToGrayMenu.Text = 'ColorToGray';
            app.b = uimenu(app.Menu);
            app.b.MenuSelectedFcn = createCallbackFcn(app, @bSelected, true);
            app.b.Accelerator = 'b';
            app.b.Text = 'PictureStrong';
            app.GrayToBinMenu = uimenu(app.Menu);
            app.GrayToBinMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayToBinMenuSelected, true);
            app.GrayToBinMenu.Accelerator = 'j';
            app.GrayToBinMenu.Text = 'GrayToBin';
            app.GrayTransMenu = uimenu(app.Menu);
            app.GrayTransMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayTransMenuSelected, true);
            app.GrayTransMenu.Accelerator = 'd';
            app.GrayTransMenu.Text = 'GrayTrans';
            app.e = uimenu(app.Menu);
            app.e.MenuSelectedFcn = createCallbackFcn(app, @eMenuSelected, true);
            app.e.Accelerator = 'e';
            app.e.Text = 'Filter';
            app.EdgeExtractionMenu = uimenu(app.Menu);
            app.EdgeExtractionMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeExtractionMenuSelected, true);
            app.EdgeExtractionMenu.Accelerator = 'f';
            app.EdgeExtractionMenu.Text = 'EdgeExtraction';
            app.AddNoiseMenu = uimenu(app.Menu);
            app.AddNoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @AddNoiseMenuSelected, true);
            app.AddNoiseMenu.Accelerator = 'g';
            app.AddNoiseMenu.Text = 'AddNoise';
            app.h = uimenu(app.Menu);
            app.h.MenuSelectedFcn = createCallbackFcn(app, @hMenuSelected, true);
            app.h.Accelerator = 'h';
            app.h.Text = 'HistogramEqu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原图像')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '转换后图像')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像处理小程序——专注毁容一伯年';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '转换';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 256];
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '下载';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前模式';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalWork1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end